classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ComportamientohidrulicoDropDown  matlab.ui.control.DropDown
        ViscocidaddinmicaEditField    matlab.ui.control.NumericEditField
        CalcularButton                matlab.ui.control.StateButton
        PipefrictionLabel             matlab.ui.control.Label
        ViscocidaddinmicaLabel        matlab.ui.control.Label
        DensityLabel                  matlab.ui.control.Label
        DensityEditField              matlab.ui.control.NumericEditField
        DiametroEditFieldLabel        matlab.ui.control.Label
        DiametroEditField             matlab.ui.control.NumericEditField
        ComportamientohidrulicoDropDownLabel  matlab.ui.control.Label
        VelocityEditFieldLabel        matlab.ui.control.Label
        VelocityEditField             matlab.ui.control.NumericEditField
        RugosidadLabel                matlab.ui.control.Label
        RugosidadEditField            matlab.ui.control.NumericEditField
        FrictionfactorEditFieldLabel  matlab.ui.control.Label
        FrictionfactorEditField       matlab.ui.control.EditField
        ErrorLabel                    matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        InitialValueEditFieldLabel    matlab.ui.control.Label
        InitialValueEditField         matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        NumberofiterationsEditField   matlab.ui.control.NumericEditField
        mtsLabel                      matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        mtsLabel_2                    matlab.ui.control.Label
        Nsm2Label                     matlab.ui.control.Label
        kgm3Label                     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        GraficarButton                matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if(app.ComportamientohidrulicoDropDown.Value=="Flujo hicráulicamente semi rugoso")
                disp(app.ComportamientohidrulicoDropDown.Value);
                rho=app.DensityEditField.Value;
                mu=app.ViscocidaddinmicaEditField.Value;
                D=app.DiametroEditField.Value;
                V=app.VelocityEditField.Value;
                e=app.RugosidadEditField.Value;
                Re=rho*V*D/mu;
                syms x;
                f = 1/(sqrt(x))+(2*log10( (e/(3.7*D))+(2.51/(Re*sqrt(x)))));
                f1=(diff(f));
            elseif (app.ComportamientohidrulicoDropDown.Value=="Flujo hidráulicamente lisa")
                rho=app.DensityEditField.Value;
                mu=app.ViscocidaddinmicaEditField.Value;
                D=app.DiametroEditField.Value;
                V=app.VelocityEditField.Value;
                Re=rho*V*D/mu;
                syms x;
                f = 1/(x^(1/2))+(2*log10((2.51/(Re*sqrt(x))) ));
                f1=(diff(f));
                
            else
                disp(app.ComportamientohidrulicoDropDown.Value);
                e=app.RugosidadEditField.Value;
                D=app.DiametroEditField.Value;
                syms x;
                f = 1/(x^(1/2))+(2*log10( (e/(3.7*D)) ));
                f1=(diff(f));
                
            end
            
            
            ed = app.ErrorEditField.Value;
            x=app.InitialValueEditField.Value;
            
            maxiter=app.NumberofiterationsEditField.Value;
            iter=0;
            ea=100;
            xold=x;
            while iter<maxiter && ea>ed
                x=xold-(subs(f)/subs(f1));
                if x~=0
                    ea=(abs(x-xold)/x);
                end
                xold=x;
                iter=iter+1;
            end
            if iter ~= maxiter
                disp(vpa(x));
                x=vpa(x);
                app.FrictionfactorEditField.Value =string(x);
            else
                app.FrictionfactorEditField.Value="No se puede obtener una respuesta porfavor pruebe con diferentes datos";
            end
            return 

        end

        % Value changed function: ComportamientohidrulicoDropDown
        function ComportamientohidrulicoDropDownValueChanged(app, event)
            value = app.ComportamientohidrulicoDropDown.Value;
            if value=="Flujo hicráulicamente semi rugoso"
                set(app.DensityEditField, 'enable', 'on');
                set(app.ViscocidaddinmicaEditField, 'enable', 'on');
                set(app.DiametroEditField, 'enable', 'on');
                set(app.VelocityEditField, 'enable', 'on');
                set(app.RugosidadEditField, 'enable', 'on');
            elseif value=="Flujo hidráulicamente lisa"
                set(app.DensityEditField, 'enable', 'on');
                set(app.ViscocidaddinmicaEditField, 'enable', 'on');
                set(app.DiametroEditField, 'enable', 'on');
                set(app.VelocityEditField, 'enable','on');
                set(app.RugosidadEditField, 'enable','off');
                
            else
                set(app.DensityEditField, 'enable','off');
                set(app.ViscocidaddinmicaEditField, 'enable','off');
                set(app.DiametroEditField, 'enable','on');
                set(app.VelocityEditField, 'enable','off');
                set(app.RugosidadEditField, 'enable','on');
            end
                    
            
        end

        % Value changed function: GraficarButton
        function GraficarButtonPushed(app, event)
            if app.ComportamientohidrulicoDropDown.Value=="Flujo hicráulicamente semi rugoso"
                rho=app.DensityEditField.Value;
                mu=app.ViscocidaddinmicaEditField.Value;
                D=app.DiametroEditField.Value;
                V=app.VelocityEditField.Value;
                e=app.RugosidadEditField.Value;
                Re=rho*V*D/mu;
                syms x;
                f = 1/(x^(1/2))+(2*log10((2.51/(Re*sqrt(x))) ));
                fplot(f);
            elseif(app.ComportamientohidrulicoDropDown.Value=="Flujo hidráulicamente lisa")
                rho=app.DensityEditField.Value;
                mu=app.ViscocidaddinmicaEditField.Value;
                D=app.DiametroEditField.Value;
                V=app.VelocityEditField.Value;
                Re=rho*V*D/mu;
                syms x;
                f = 1/(x^(1/2))+(2*log10((2.51/(Re*sqrt(x))) ));
                fplot(f);
            else
                disp(app.ComportamientohidrulicoDropDown.Value);
                e=app.RugosidadEditField.Value;
                D=app.DiametroEditField.Value;
                syms x;
                f = 1/(x^(1/2))+(2*log10( (e/(3.7*D)) ));
                fplot(f);
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ComportamientohidrulicoDropDown = uidropdown(app.UIFigure);
            app.ComportamientohidrulicoDropDown.Items = {'Flujo hidráulicamente lisa', 'Flujo hicráulicamente semi rugoso', 'Flujo hidráulicamnte rugoso'};
            app.ComportamientohidrulicoDropDown.ValueChangedFcn = createCallbackFcn(app, @ComportamientohidrulicoDropDownValueChanged, true);
            app.ComportamientohidrulicoDropDown.Value = 'Flujo hicráulicamente semi rugoso';
            app.ViscocidaddinmicaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.PipefrictionLabel = uilabel(app.UIFigure);
            app.PipefrictionLabel.Text = 'Pipe friction';
            app.ViscocidaddinmicaLabel = uilabel(app.UIFigure);
            app.ViscocidaddinmicaLabel.Text = 'Viscocidad dinámica(μ)';
            app.DensityLabel = uilabel(app.UIFigure);
            app.DensityLabel.Text = 'Density (ρ)';
            app.DensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametroEditFieldLabel = uilabel(app.UIFigure);
            app.DiametroEditFieldLabel.Text = 'Diametro ';
            app.DiametroEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComportamientohidrulicoDropDownLabel = uilabel(app.UIFigure);
            app.ComportamientohidrulicoDropDownLabel.Text = 'Comportamiento hidráulico';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RugosidadLabel = uilabel(app.UIFigure);
            app.RugosidadLabel.Text = 'Rugosidad (ε)';
            app.RugosidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.RugosidadEditField.Limits = [0 Inf];
            app.FrictionfactorEditFieldLabel = uilabel(app.UIFigure);
            app.FrictionfactorEditFieldLabel.Text = 'Friction factor';
            app.FrictionfactorEditField = uieditfield(app.UIFigure, 'text');
            app.FrictionfactorEditField.Editable = 'off';
            app.FrictionfactorEditField.Enable = 'off';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error ';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditField.Limits = [0 100];
            app.ErrorEditField.Value = 1;
            app.InitialValueEditFieldLabel = uilabel(app.UIFigure);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialValueEditField.Value = 1;
            app.NumberofiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationsEditField.Limits = [1 200];
            app.NumberofiterationsEditField.Value = 50;
            app.mtsLabel = uilabel(app.UIFigure);
            app.mtsLabel.Text = 'mts';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.mtsLabel_2 = uilabel(app.UIFigure);
            app.mtsLabel_2.Text = 'mts';
            app.Nsm2Label = uilabel(app.UIFigure);
            app.Nsm2Label.Text = 'N*s/(m^2)';
            app.kgm3Label = uilabel(app.UIFigure);
            app.kgm3Label.Text = 'kg/m^3';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.GraficarButton = uibutton(app.UIFigure, 'state');
            app.GraficarButton.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end